classdef dataVsMatch_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        rawDataPlot        matlab.ui.control.UIAxes
        signals            matlab.ui.control.UIAxes
        uipanel1           matlab.ui.container.Panel
        dataSelector1      matlab.ui.control.DropDown
        startButton        matlab.ui.control.Button
        frameNum           matlab.ui.control.Label
        coordDisp          matlab.ui.control.Label
        matchIdx           matlab.ui.control.Label
        avgSlider          matlab.ui.control.Slider
        avgDisp            matlab.ui.control.Label
        image1             matlab.ui.control.UIAxes
        image2             matlab.ui.control.UIAxes
        image3             matlab.ui.control.UIAxes
        dataSelector2      matlab.ui.control.DropDown
        SlideSpinnerLabel  matlab.ui.control.Label
        SlideSpinner       matlab.ui.control.Spinner
        dataSelector3      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Images = evalin('base', 'Images');% Description
        dictionary = evalin('base', 'dictionary');
        Reconstruction = evalin('base', 'Reconstruction');
        Properties = evalin('base', 'Properties');
        signalsDrawn = 0;
    end
    
    methods (Access = private)
        function changePlot(app, localHandle, choice)
            % executes chen changing selection below plots
            switch choice
                case 'Score'
                    clickableImage(app, localHandle, app.Reconstruction.innerproduct);
                    caxis(localHandle, [min(app.Reconstruction.innerproduct, [], 'All'), max(app.Reconstruction.innerproduct, [], 'All')])
                    colorbar(localHandle)
                case 'PD'
                    clickableImage(app, localHandle, app.Reconstruction.PDmap);
                    caxis(localHandle, [min(app.Reconstruction.PDmap, [], 'All'), max(app.Reconstruction.PDmap, [], 'All')])
                    colorbar(localHandle)
                case 'T1'
                    clickableImage(app, localHandle, app.Properties.T1list(app.Reconstruction.idxMatch));
                    caxis(localHandle, [min(app.Properties.T1list(app.Reconstruction.idxMatch), [], 'All'), max(app.Properties.T1list(app.Reconstruction.idxMatch), [], 'All')])
                    colorbar(localHandle)
                case 'T2'
                    clickableImage(app, localHandle, app.Properties.T2list(app.Reconstruction.idxMatch));
                    caxis(localHandle, [min(app.Properties.T2list(app.Reconstruction.idxMatch), [], 'All'), max(app.Properties.T2list(app.Reconstruction.idxMatch), [], 'All')])
                    colorbar(localHandle)
                case 'df'
                    clickableImage(app, localHandle, abs(app.Properties.dflist(app.Reconstruction.idxMatch)));
                    if numel(app.Properties.df)>1
                        caxis(localHandle, [min(abs(app.Properties.dflist(app.Reconstruction.idxMatch)), [], 'All'), max(abs(app.Properties.dflist(app.Reconstruction.idxMatch)), [], 'All')])
                        colorbar(localHandle)
                    end
                case 'B1'
                    clickableImage(app, localHandle, app.Properties.B1rellist(app.Reconstruction.idxMatch));
                    if numel(app.Properties.B1rel)>1
                        caxis(localHandle, [min(app.Properties.B1rellist(app.Reconstruction.idxMatch), [], 'All'), max(app.Properties.B1rellist(app.Reconstruction.idxMatch), [], 'All')])
                        colorbar(localHandle)
                    end
                otherwise
                    clickableImage(app, localHandle, zeros(size(app.Images.Images_dicom, 1), size(app.Images.Images_dicom, 2)));
            end
            set(localHandle, 'ColorMap', parula)
            set(localHandle,'xtick',[]); set(localHandle,'ytick',[]);
        end
        
        function clickableImage(app, localHandle, dataToDisplay, cmap)
            if nargin<4
                cmap=parula;
            end
%             image(dataToDisplay, 'CDataMapping', 'Scaled', 'Parent', localHandle)
            image(localHandle, dataToDisplay, 'CDataMapping', 'Scaled')
            localHandle.XLim=[1, app.Images.Nx];
            localHandle.YLim=[1, app.Images.Ny];
            set(localHandle, 'ColorMap', cmap)
            set(get(localHandle, 'Children'), 'HitTest', 'off');
%             set(localHandle,'ButtonDownFcn', @getCoord);
            set(get(localHandle, 'Children'), 'ButtonDownFcn', @getCoord);
%             set(localHandle,'xtick',[]); set(localHandle,'ytick',[]);
        end
        
        function drawSignals(app, hObject, eventdata, handles)
            % Executed when clicking on a plot
            X = 1:app.Images.Nimages;
            if app.avgSlider.Value == 1
                rawData = squeeze(app.Images.Image_normalized_dicom(app.x,app.y,:));
                match = abs(app.dictionary(app.Reconstruction.idxMatch(app.x,app.y),:))/norm(abs(app.dictionary(app.Reconstruction.idxMatch(app.x,app.y),:)));
            else
                avg = app.avgSlider.Value;
                rawData = zeros(size(squeeze(app.Images.Image_normalized_dicom(app.x,app.y,:))));
                match = rawData';
                count = 0;
                for i = 0:2*avg-2
                    for j=0:2*avg-2
                        rawData = rawData + squeeze(app.Images.Image_normalized_dicom(app.x-avg-1+i,app.y-avg-1+j,:));
                        match = match + abs(app.dictionary(app.Reconstruction.idxMatch(app.x-avg-1+i,app.y-avg-1+j),:))/norm(abs(app.dictionary(app.Reconstruction.idxMatch(app.x-avg-1+i,app.y-avg-1+j),:)));
                        count = count+1;
                    end
                end
                rawData = rawData/count;
                match = match/count;
            end
            set(app.matchIdx, 'String', sprintf('Match #: %i', app.Reconstruction.idxMatch(app.x,app.y)));
            set(app.signals, 'nextPlot','replacechildren');
            cla(app.signals);
            axes(app.signals)
            plot(X, rawData, 'b', X, match, 'r')
            legend(app.signals, 'Acquired', 'Match')
            app.signalsDrawn = 1;
            guidata(hObject, handles)
        end
        
        function getCoord(app)
            % hObject    handle to rawDataPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles = guidata(hObject);
            C = get(hObject, 'CurrentPoint');
            x=round(C(1,2));
            y=round(C(1,1));
            if x > size(app.Images.Images_dicom,1)
                x = size(app.Images.Images_dicom,1);
            end
            if y > size(app.Images.Images_dicom,1)
                y = size(app.Images.Images_dicom,1);
            end
            set(app.coordDisp, 'String', sprintf('X: %i, Y: %i', x, y));
            app.x = x;
            app.y = y;
            drawSignals(hObject, eventdata, handles);
            guidata(hObject, handles)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dataVsMatch_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to dataVsMatch (see VARARGIN)
%             
%             % Choose default command line output for dataVsMatch
%             app.output = hObject;
%             % app.Images = evalin('base', 'Images');
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: avgSlider
        function avgSlider_Callback(app, event)
            % hObject    handle to avgSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.avgDisp, 'String', sprintf('Averaging: %i', round(app.avgSlider.Value)))
            if app.signalsDrawn==1
                drawSignals(hObject, eventdata, handles)
            end
        end

        % Value changed function: dataSelector1
        function dataSelector1_Callback(app, event)
            % hObject    handle to dataSelector1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            localHandle = app.image1;
%             choice = cellstr(get(hObject,'String'));
%             choice = choice{get(hObject,'Value')};
            choice = app.dataSelector1.Value;
            changePlot(app, localHandle, choice);
        end

        % Value changed function: dataSelector2
        function dataSelector2_Callback(app, event)
            % hObject    handle to dataSelector2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns dataSelector2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from dataSelector2
            localHandle = app.image2;
            choice = app.dataSelector2.Value;
            changePlot(app, localHandle, choice);
        end

        % Value changed function: dataSelector3
        function dataSelector3_Callback(app, event)
            % hObject    handle to dataSelector3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns dataSelector3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from dataSelector3
            localHandle = app.image3;
            choice = app.dataSelector3.Value;
            changePlot(app, localHandle, choice);
        end

        % Button pushed function: startButton
        function startButton_Callback(app, event)
            % hObject    handle to startButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Get data from local env
%             app.Images = evalin('base', 'Images');
%             app.dictionary = evalin('base', 'dictionary');
%             app.Reconstruction = evalin('base', 'Reconstruction');
%             app.Properties = evalin('base', 'Properties');
%             app.signalsDrawn = 0;
%             
            % set(app.rawDataPlot, 'PickableParts', 'all');
            % Set raw plot


            clickableImage(app, app.rawDataPlot, squeeze(app.Images.Images_dicom(:,:,round(size(app.Images.Images_dicom,3)/2))), gray)
            set(app.rawDataPlot,'xtick',[]); set(app.rawDataPlot,'ytick',[]);
%             set(app.rawDataPlot, 'ColorMap', gray);
            % Set slice selector initial value
%             set(app.sliceSelector, 'String', num2str(round(size(app.Images.Images_dicom,4)/2)));
            % Set slice selector range
            % maxPt = round(min(size(app.Images.Images_dicom,1), size(app.Images.Images_dicom,2))/2);
            maxPt = 5;
            if size(app.Images.Images_dicom,4)>1
                set(app.SlideSpinner, 'Limits', [1 size(app.Images.Images_dicom,4)]);
            else
                set(app.SlideSpinner, 'Enable', 'off');
            end
            
            set(app.frameNum, 'Text', sprintf('Frames : %i/%i', round(size(app.Images.Images_dicom,3)/2), size(app.Images.Images_dicom,3) ));
            set(app.avgSlider, 'Limits', [1, maxPt]);
%             set(app.avgSlider, 'SliderStep', [1,1]/(maxPt-1), 'Value', 1)
            set(app.avgDisp, 'Text', sprintf('Averaging: 1'));
%             guidata(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'dataVsMatch';
            app.rawDataPlot = uiaxes(app.figure1);
            app.rawDataPlot.XTick = [];
            app.rawDataPlot.YTick = [];
            app.signals = uiaxes(app.figure1);
            app.uipanel1 = uipanel(app.figure1);
            app.dataSelector1 = uidropdown(app.figure1);
            app.dataSelector1.Items = {'-', 'Score', 'T1', 'T2', 'PD', 'df', 'B1', 'v', ''};
            app.dataSelector1.ValueChangedFcn = createCallbackFcn(app, @dataSelector1_Callback, true);
            app.dataSelector1.Value = '-';
            app.startButton = uibutton(app.figure1, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
            app.startButton.Text = 'Start';
            app.frameNum = uilabel(app.figure1);
            app.frameNum.Text = 'Frame :';
            app.coordDisp = uilabel(app.figure1);
            app.coordDisp.Text = 'X: Y:';
            app.matchIdx = uilabel(app.figure1);
            app.matchIdx.Text = 'Match #: ';
            app.avgSlider = uislider(app.figure1);
            app.avgSlider.Limits = [0 1];
            app.avgSlider.MajorTicks = [];
            app.avgSlider.ValueChangedFcn = createCallbackFcn(app, @avgSlider_Callback, true);
            app.avgSlider.MinorTicks = [];
            app.avgSlider.Value = 1;
            app.avgDisp = uilabel(app.figure1);
            app.avgDisp.Text = 'Averaging:';
            app.image1 = uiaxes(app.figure1);
            app.image1.XTick = [];
            app.image1.YTick = [];
            app.image2 = uiaxes(app.figure1);
            app.image2.XTick = [];
            app.image2.YTick = [];
            app.image3 = uiaxes(app.figure1);
            app.image3.XTick = [];
            app.image3.YTick = [];
            app.dataSelector2 = uidropdown(app.figure1);
            app.dataSelector2.Items = {'-', 'Score', 'T1', 'T2', 'PD', 'df', 'B1', 'v', ''};
            app.dataSelector2.ValueChangedFcn = createCallbackFcn(app, @dataSelector2_Callback, true);
            app.dataSelector2.Value = '-';
            app.dataSelector3 = uidropdown(app.figure1);
            app.dataSelector3.Items = {'-', 'Score', 'T1', 'T2', 'PD', 'df', 'B1', 'v', ''};
            app.dataSelector3.ValueChangedFcn = createCallbackFcn(app, @dataSelector3_Callback, true);
            app.dataSelector3.Value = '-';
            app.SlideSpinnerLabel = uilabel(app.figure1);
            app.SlideSpinnerLabel.Text = 'Slide';
            app.SlideSpinner = uispinner(app.figure1);
            app.SlideSpinner.Limits = [1 Inf];
            app.SlideSpinner.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataVsMatch_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @dataVsMatch_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end